classdef MorganEva_ProjectAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        LoadDataButton                matlab.ui.control.Button
        SelectRegionofInterestButton  matlab.ui.control.Button
        CalculateHeartRateButton      matlab.ui.control.Button
        HeartRateTextAreaLabel        matlab.ui.control.Label
        HeartRateTextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        info1;
        VIDEO;
        n;
        m;
        xmin;
        xmax;
        ymin;
        ymax;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadData(app, event)
     [filename, pathname] = uigetfile('*.*');
     app.info1 = VideoReader([pathname, filesep, filename]);
     app.n = app.info1.NumFrames;
     app.m = app.info1.Duration;  %duration/frames = frame rate
     app.VIDEO = readFrame(app.info1);
     imagesc(app.VIDEO);
        end

        % Button pushed function: SelectRegionofInterestButton
        function SelectROI(app, event)
           a = getrect;
           app.xmax = a(:,1) + a(:,3)
           app.xmin = a(:,1)
           app.ymin = a(:,2)
           app.ymax = a(:,2) + a(:,4) 
        end

        % Button pushed function: CalculateHeartRateButton
        function CalculateHR(app, event)
            for f = 1:app.n   
                 app.VIDEO = readFrame(app.info1);
                 subregion = app.VIDEO(app.xmin:app.xmax,app.ymin:app.ymax);
                 a(f) = mean(subregion,'all')
    
            end 
          plot(a);
          c = findpeaks(a);
          heartbeats = c*app.m %gives seconds 
          app.HeartRateText.Value = [num2str(heartbeats)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SelectRegionofInterestButton = uibutton(app.UIFigure, 'push');
            app.SelectRegionofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROI, true);
            app.SelectRegionofInterestButton.Text = 'Select Region of Interest';
            app.CalculateHeartRateButton = uibutton(app.UIFigure, 'push');
            app.CalculateHeartRateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateHR, true);
            app.CalculateHeartRateButton.Text = 'Calculate Heart Rate';
            app.HeartRateTextAreaLabel = uilabel(app.UIFigure);
            app.HeartRateTextAreaLabel.Text = 'Heart Rate';
            app.HeartRateTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MorganEva_ProjectAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end